classdef SleepDataTTcreate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        FileMenu     matlab.ui.container.Menu
        LoadDate     matlab.ui.container.Menu
        ChannelMenu  matlab.ui.container.Menu
        Panel        matlab.ui.container.Panel
        GridLayout   matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        fileLOC    % Sleep data Folder directory
        fileLIST   % List of .mat files for entire night 
        chanlist   % AO names for channels on ECoG module
        labelTab     % Label Table
        eegTT      % Time table for EEG 
        eogTT      % Time table for EOG
        emgTT      % Time table for EMG
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadDate
        function LoadDateMenuSelected(app, event)
            app.fileLOC = uigetdir();
            
            cd(app.fileLOC)
            
            mdir = dir('*.mat');
            mdir2 = {mdir.name};
            app.fileLIST = mdir2;
            % Use Matfile function to import the appropriate files
            
            matob = matfile('F210110-0001.mat');
            varlist = who(matob);
            app.chanlist = varlist(contains(varlist,'CEEG'));
            %             ttllist = varlist(contains(varlist,'CDIG'));

            chanNum = 1:16;
            chanLab = {'EEG','EEG','LFP','EEG','EEG','EEG','LFP','EEG',...
                'EEG','LFP','EOG','EOG','EMG','EMG','EMG','LFP'};
            chanID = {'F3','Fz','D0','A1','C3','Cz','D1','A2','O1','D2',...
                'EOG1','EOG2','Chin1','Chin2','ChinZ','D3'};
            
            app.labelTab = table(chanNum,chanLab,chanID,'VariableNames',{'Number',...
                'Label','ID'});
            
            % Convert to Time tables with appropriate sample frequencies
            
            
            app.ChannelTypeMenu.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDate = uimenu(app.FileMenu);
            app.LoadDate.MenuSelectedFcn = createCallbackFcn(app, @LoadDateMenuSelected, true);
            app.LoadDate.Text = 'Load Date';
            app.ChannelMenu = uimenu(app.UIFigure);
            app.ChannelMenu.Text = 'Channel';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SleepDataTTcreate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end